classdef Run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Figure1CheckBox  matlab.ui.control.CheckBox
        Figure2CheckBox  matlab.ui.control.CheckBox
        Figure3CheckBox  matlab.ui.control.CheckBox
        Figure4CheckBox  matlab.ui.control.CheckBox
        RunButton        matlab.ui.control.Button
        Label            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            flg1 = 0; flg2 = 0; flg3 = 0; flg4 = 0;
            save('flg.mat','flg1','flg2','flg3','flg4')
%             opt = 1;
%             save('opt.mat', 'opt');
        end

        % Value changed function: Figure1CheckBox
        function Figure1CheckBoxValueChanged(app, event)
            value = app.Figure1CheckBox.Value;
%             if exist('flg.mat') == 1
                load('flg.mat','flg1','flg2','flg3','flg4')
%             else
%                 flg1 = 0; flg2 = 0; flg3 = 0; flg4 = 0;
%             end
            if value == 1
                flg1 = 1;
            else
                flg1 = 0;
            end
            save('flg.mat','flg1','flg2','flg3','flg4')
        end

        % Value changed function: Figure2CheckBox
        function Figure2CheckBoxValueChanged(app, event)
            value = app.Figure2CheckBox.Value;
%             if exist('flg.mat') == 1
                load('flg.mat','flg1','flg2','flg3','flg4')
%             else
%                 flg1 = 0; flg2 = 0; flg3 = 0; flg4 = 0;
%             end
            if value == 1
                flg2 = 1;
            else
                flg2 = 0;
            end
            save('flg.mat','flg1','flg2','flg3','flg4')
        end

        % Value changed function: Figure3CheckBox
        function Figure3CheckBoxValueChanged(app, event)
            value = app.Figure3CheckBox.Value;
%             if exist('flg.mat') == 1
                load('flg.mat','flg1','flg2','flg3','flg4')
%             else
%                 flg1 = 0; flg2 = 0; flg3 = 0; flg4 = 0;
%             end
            if value == 1
                flg3 = 1;
            else
                flg3 = 0;
            end
            save('flg.mat','flg1','flg2','flg3','flg4')
        end

        % Value changed function: Figure4CheckBox
        function Figure4CheckBoxValueChanged(app, event)
            value = app.Figure4CheckBox.Value;
%             if exist('flg.mat') == 1
                load('flg.mat','flg1','flg2','flg3','flg4')
%             else
%                 flg1 = 0; flg2 = 0; flg3 = 0; flg4 = 0;
%             end
            if value == 1
                flg4 = 1;
            else
                flg4 = 0;
            end
            save('flg.mat','flg1','flg2','flg3','flg4')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            load('flg.mat')
            if (flg1 + flg2 + flg3 + flg4) == 0
                disp('Please choose figures to display.')
            else            
            main;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Figure1CheckBox = uicheckbox(app.UIFigure);
            app.Figure1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Figure1CheckBoxValueChanged, true);
            app.Figure1CheckBox.Text = '  Figure 1';
            app.Figure2CheckBox = uicheckbox(app.UIFigure);
            app.Figure2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Figure2CheckBoxValueChanged, true);
            app.Figure2CheckBox.Text = '  Figure 2';
            app.Figure3CheckBox = uicheckbox(app.UIFigure);
            app.Figure3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Figure3CheckBoxValueChanged, true);
            app.Figure3CheckBox.Text = '  Figure 3';
            app.Figure4CheckBox = uicheckbox(app.UIFigure);
            app.Figure4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Figure4CheckBoxValueChanged, true);
            app.Figure4CheckBox.Text = '  Figure 4';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Spontaneous generation of number sense '; 'in untrained deep neural networks'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Run

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end